classdef SpecXY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpecXYUIFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SelectDirButton                 matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        GeoCPlotButton                  matlab.ui.control.Button
        WorkingDirectoryEditField       matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        WelcometoSpecXYLabel            matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        LoadSpecMapsProjectButton       matlab.ui.control.Button
        NewSpecMapsProjectButton        matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        OpenSpecDBButton                matlab.ui.control.Button
        LoadSpecDBProjectButton         matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
    end
    
    properties (Access = public)
        SpecMaps_public % Description
        SpecDB_public
        GeoCPlot_public
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed %standalone mode.
                try
                    path=ctfroot;
                    path_split=split(path,{'SpecXY.app','SpecXY.exe'});
                    path=cd(path_split{1});
                    app.WorkingDirectoryEditField.Value=path;

                    diary('SpecXY_log.txt');

                catch ME
                    uialert(app.SpecXYUIFigure,ME.message,' Error');
                    disp(ME.message)
                    disp(path)


                    try
                        answr=uiconfirm(app.SpecXYUIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                        f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                        path=uigetdir(pwd,'select working directory with read/write permission');
                        delete(f)

                        if ~isequal(path,0)
                            cd(path)
                        end
                        app.WorkingDirectoryEditField.Value=pwd;
                        diary('SpecXY_log.txt');

                    catch ME
                        uialert(app.SpecXYUIFigure,ME.message,' Error');
                    end

                end
             
            else
                app.WorkingDirectoryEditField.Value=pwd;
            end


        end

        % Button pushed function: OpenSpecDBButton
        function OpenSpecDBButtonPushed(app, event)
            app.SpecDB_public=SpecDB;
        end

        % Button pushed function: LoadSpecDBProjectButton
        function LoadSpecDBProjectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open SpecDB Project');
            delete(f)
            if not(isequal(file,0))
                app.SpecDB_public=SpecDB(fullfile(path,file));
            end
        end

        % Button pushed function: NewSpecMapsProjectButton
        function NewSpecMapsProjectButtonPushed(app, event)
if isstruct(app.SpecMaps_public) && not(isempty(app.SpecMaps_public))
                app.SpecMaps_public(end+1).Data=SpecMaps;
         else
                app.SpecMaps_public.Data=SpecMaps;

end
% app.SpecMaps_public.Settings=app.Settings.SpecMaps;
        end

        % Button pushed function: LoadSpecMapsProjectButton
        function LoadSpecMapsProjectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open SpecMaps Project');
            delete(f)
            if not(isequal(file,0))
                %      app.SpecMaps_public=SpecMaps(fullfile(path,file));
                if isstruct(app.SpecMaps_public) && not(isempty(app.SpecMaps_public))
                    app.SpecMaps_public(end+1).Data=SpecMaps(fullfile(path,file));
                else
                    app.SpecMaps_public.Data=SpecMaps(fullfile(path,file));

                end
            end
        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;
            if not(exist(value,'dir'))
                answr=uiconfirm(app.SpecXYUIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)

                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value=path;
                    diary('SpecXY_log.txt');
                end
            else
                cd(app.WorkingDirectoryEditField.Value)
                diary('SpecXY_log.txt');
            end

        end

        % Button pushed function: GeoCPlotButton
        function GeoCPlotButtonPushed(app, event)
          app.GeoCPlot_public=GeoCPlot;
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
                            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
path=uigetdir(pwd,'select working directory with read/write permission');
            delete(f)
            if ~isequal(path,0)
                                app.WorkingDirectoryEditField.Value=path;
                WorkingDirectoryEditFieldValueChanged(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpecXYUIFigure = uifigure('Visible', 'off');
            app.SpecXYUIFigure.Name = 'SpecXY';
            app.SpecXYUIFigure.Icon = 'symbols_1_logoSpecXY.png';
            app.GridLayout = uigridlayout(app.SpecXYUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'symbols_1_logo-14.png';
            app.LoadSpecDBProjectButton = uibutton(app.GridLayout, 'push');
            app.LoadSpecDBProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecDBProjectButtonPushed, true);
            app.LoadSpecDBProjectButton.Text = {'Load SpecDB '; 'Project'};
            app.OpenSpecDBButton = uibutton(app.GridLayout, 'push');
            app.OpenSpecDBButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSpecDBButtonPushed, true);
            app.OpenSpecDBButton.Text = 'Open SpecDB';
            app.Image_2 = uiimage(app.GridLayout);
            app.Image_2.ImageSource = 'symbols_1_logo.png';
            app.NewSpecMapsProjectButton = uibutton(app.GridLayout, 'push');
            app.NewSpecMapsProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewSpecMapsProjectButtonPushed, true);
            app.NewSpecMapsProjectButton.Text = {'New SpecMaps '; 'Project'};
            app.LoadSpecMapsProjectButton = uibutton(app.GridLayout, 'push');
            app.LoadSpecMapsProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecMapsProjectButtonPushed, true);
            app.LoadSpecMapsProjectButton.Text = {'Load SpecMaps '; 'Project'};
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'symbols_1_logoSpecXY.png';
            app.WelcometoSpecXYLabel = uilabel(app.GridLayout);
            app.WelcometoSpecXYLabel.Text = 'Welcome to SpecXY';
            app.WorkingDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.GeoCPlotButton = uibutton(app.GridLayout, 'push');
            app.GeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotButtonPushed, true);
            app.GeoCPlotButton.Text = 'GeoCPlot';
            app.Image_3 = uiimage(app.GridLayout);
            app.Image_3.ImageSource = 'symbols_1_GeoCPlot.png';
            app.SelectDirButton = uibutton(app.GridLayout, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            % Show the figure after all components are created
            app.SpecXYUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecXY

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpecXYUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SpecXYUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpecXYUIFigure)
        end
    end
end